classdef DataAcquisition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        OverlapEditField          matlab.ui.control.NumericEditField
        OverlapEditFieldLabel     matlab.ui.control.Label
        BlocksizeEditField        matlab.ui.control.NumericEditField
        BlocksizeEditFieldLabel   matlab.ui.control.Label
        DamageEditField           matlab.ui.control.NumericEditField
        DamageEditFieldLabel      matlab.ui.control.Label
        PeriodEditField           matlab.ui.control.NumericEditField
        PeriodEditFieldLabel      matlab.ui.control.Label
        SamplerateEditField       matlab.ui.control.NumericEditField
        SamplerateEditFieldLabel  matlab.ui.control.Label
        SignalsLabel              matlab.ui.control.Label
        Tree                      matlab.ui.container.CheckBoxTree
        Button                    matlab.ui.control.Button
        DisconnectButton          matlab.ui.control.Button
        ConnectButton             matlab.ui.control.Button
        CenterPanel               matlab.ui.container.Panel
        GridLayout2               matlab.ui.container.GridLayout
        RealTimeSignalLabel       matlab.ui.control.Label
        GridLayout4               matlab.ui.container.GridLayout
        UIAxes2_2                 matlab.ui.control.UIAxes
        GridLayout3               matlab.ui.container.GridLayout
        UIAxes                    matlab.ui.control.UIAxes
        RightPanel                matlab.ui.container.Panel
        Graph2ListBox             matlab.ui.control.ListBox
        Graph2ListBoxLabel        matlab.ui.control.Label
        OutputsTextArea           matlab.ui.control.TextArea
        OutputsTextAreaLabel      matlab.ui.control.Label
        InputsTextArea            matlab.ui.control.TextArea
        InputsTextAreaLabel       matlab.ui.control.Label
        SetoutputButton           matlab.ui.control.Button
        DatabaseEditField         matlab.ui.control.EditField
        DatabaseEditFieldLabel    matlab.ui.control.Label
        ListBox                   matlab.ui.control.ListBox
        ListBoxLabel              matlab.ui.control.Label
        save                      matlab.ui.control.Button
        SetInputButton            matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        CallingApp % Description
        d % Lista inputs
        devices=[string] % Lista de dispositivos
        inputs=[string] % Description
        dq
        doplot %boolean
        datarray
        selectedinput
        outputs=[strings]
        x
        y
        T
        M
    end
    
    methods (Access = private)
        
        function dq=Connect(app)
            % Add object connecting to DAQ board
            dq = daq("ni");
            dq.Rate = 10240;% Set sampling rate (just to start, it will be re-adjusted later)
            % Add the channels
            chnodes=app.Tree.CheckedNodes;
            for i=1:length(chnodes)
                node=chnodes(i);
                if length(node.Children)<1
                    parent=node.Parent;
                    addinput(dq,parent.Text ,node.Text,'IEPE');
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.CallingApp = caller;
            app.UIAxes2_2.Title.String="PSD";
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.doplot=0;
            app.CallingApp.DataAcquisitionButton.Enable = "on";
            app.CallingApp.MonitoringPlatformUIFigure.Visible="on";
            delete(app)
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.ConnectButton.Enable="off";
            app.dq=Connect(app);
            app.dq.Rate=app.SamplerateEditField.Value;
            app.datarray = [];
            n = ceil(app.dq.Rate);
            start(app.dq,"continuous");
            app.doplot=1;
            app.DisconnectButton.Enable="on";
            data = read(app.dq,n);
            app.datarray = [app.datarray; data];
            app.ListBox.Items=app.datarray.Properties.VariableNames;
            app.ListBox.Enable="on";
            app.SetInputButton.Enable="on";
            app.SetoutputButton.Enable="on";
            app.selectedinput="";
            app.outputs=[strings];
            app.InputsTextArea.Value=app.selectedinput;
            app.OutputsTextArea.Value=app.outputs;
            
            while app.doplot % Increase or decrease the pause duration to fit your needs.
                t=tic;
                while toc(t)<app.PeriodEditField.Value
                    data = read(app.dq,n);
                    app.datarray = [app.datarray; data];
                    % live plotting.
                    app.x=app.datarray.Time;
                    app.y=app.datarray.Variables;
                    if height(app.datarray)<n*30
                        plot(app.UIAxes,app.x,app.y);
                    else
                        plot(app.UIAxes,app.x(end-n*30+1:end, :),app.y(end-n*30+1:end, :));
                    end
                    legend(app.UIAxes,app.datarray.Properties.VariableNames, "Interpreter", "none")
                end
                app.T= app.PeriodEditField.Value;
                N = app.T*n;
                app.M = app.BlocksizeEditField.Value;
                nOver = app.OverlapEditField.Value;
                f=app.dq.Rate*(0:app.M/2)/app.M;
                if app.Graph2ListBox.Value=="PSD"
                    Pyy=pwelch(app.y(end-N+1:end,:),hann(app.M),nOver,app.M,1/app.T);
                    plot(app.UIAxes2_2,f,10*log10(Pyy));
                    app.UIAxes2_2.YLabel.String="PSD [dB]";
                    app.UIAxes2_2.Title.String="PSD";
                    legend(app.UIAxes2_2,app.datarray.Properties.VariableNames, "Interpreter", "none")
                else
                    if size(app.y,2)>1
                        if app.selectedinput~=""
                            indice=0;
                            for i=1:size(app.datarray.Variables,2)
                                value=string(app.ListBox.Items(i));
                                if app.selectedinput==value
                                        indice=i;
                                end
                            end
                            xx=app.y(:,indice);
                            yy=app.y;
                            yy(:,indice)=[];
                            Pxx = pwelch(xx(end-N+1:end),hann(app.M),nOver,app.M,1/app.T);
                            Pxy = cpsd(xx(end-N+1:end),yy(end-N+1:end,:),hann(app.M),nOver,app.M,1/app.T);
                            Pyy = pwelch(yy(end-N+1:end,:),hann(app.M),nOver,app.M,1/app.T);
                        else
                            Pxx = pwelch(app.y(end-N+1:end,1),hann(app.M),nOver,app.M,1/app.T);
                            Pxy = cpsd(app.y(end-N+1:end,1),app.y(end-N+1:end,2:end),hann(app.M),nOver,app.M,1/app.T);
                            Pyy = pwelch(app.y(end-N+1:end,2:end),hann(app.M),nOver,app.M,1/app.T);
                        end
                        if app.Graph2ListBox.Value=="FRF"
                            H = abs(Pxy./Pxx);
                            app.UIAxes2_2.YLabel.String="FRF magnitude [dB]";
                            app.UIAxes2_2.Title.String="FRF";
                            plot(app.UIAxes2_2,f,10*log10(H));
                        elseif app.Graph2ListBox.Value=="Coherence"
                            H1 = Pxy./Pxx;
                            H2 = Pyy./conj(Pxy);
                            gamma2 = abs(H1./H2);
                            % gamma2 = abs(Pxy).^2 ./ ( Pxx.*Pyy );
                            app.UIAxes2_2.YLabel.String="Coherence";
                            app.UIAxes2_2.Title.String="Coherence";
                            plot(app.UIAxes2_2,f,gamma2);
                        end
                    end
                end
            end
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % Check available DAQ systems (may take some time the first time)
            app.ConnectButton.Enable="off";
            app.d = daqlist; 
            disp(app.d)
            disp('Device information')
            disp(app.d{:,'DeviceInfo'})
            size=height(app.d);
            index=1;
            app.Tree.Children.delete
            for i = 1:size
                info=app.d{i,'DeviceInfo'};
                disp(info.Subsystems.ChannelNames)
                for k=1:info.Subsystems.NumberOfChannelsAvailable
                   app.devices(index,1)={app.d{i,2}};
                   channel=string(info.Subsystems.ChannelNames(k));
                   app.inputs(index,1)=channel;
                   index=index+1;
                end
            end
            for i=1:size
                dev=string({app.d{i,2}});
                devnode=uitreenode(app.Tree,"Text",dev);
                for j=1:info.Subsystems.NumberOfChannelsAvailable
                    text = app.inputs{j};
                    uitreenode(devnode,"Text",text);
                end
            end
             app.ConnectButton.Enable="on";          
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            stop(app.dq);
            app.doplot=0;
            app.DisconnectButton.Enable="off";
            app.ConnectButton.Enable="on";
        end

        % Button pushed function: save
        function saveButtonPushed(app, event)
            text=string(append(app.DatabaseEditField.Value,".mat"));
            data=app.datarray;
            rate=app.dq.Rate;
            Period=app.PeriodEditField.Value;
            Damage=app.DamageEditField.Value;
            input=app.selectedinput;
            outputs=app.outputs;
            save(text,"data","rate","Period","Damage","input","outputs","-mat")
        end

        % Button pushed function: SetInputButton
        function SetInputButtonPushed(app, event)
            app.selectedinput=app.ListBox.Value;
            app.InputsTextArea.Value=app.selectedinput;
            app.outputs(contains(app.outputs,app.ListBox.Value))=[];
            app.OutputsTextArea.Value=app.outputs;
        end

        % Button pushed function: SetoutputButton
        function SetoutputButtonPushed(app, event)
            if app.ListBox.Value==app.selectedinput
                app.selectedinput="";
                app.InputsTextArea.Value=app.selectedinput;
            end
            if app.outputs==""
                app.outputs=[string(app.ListBox.Value)];
            else
                app.outputs=[app.outputs, app.ListBox.Value];
                app.outputs=unique(app.outputs);
            end
            app.OutputsTextArea.Value=app.outputs;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {579, 579, 579};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {579, 579};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 213};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ConnectButton = uibutton(app.LeftPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Enable = 'off';
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.LeftPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Enable = 'off';
            app.DisconnectButton.Text = 'Disconnect';
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Tag = 'search';
            app.Button.Icon = fullfile(pathToMLAPP, 'searchicon.png');
            app.Button.Text = '';
            app.Tree = uitree(app.LeftPanel, 'checkbox');
            app.Tree.Editable = 'on';
            app.SignalsLabel = uilabel(app.LeftPanel);
            app.SignalsLabel.Text = 'Signals';
            app.SamplerateEditFieldLabel = uilabel(app.LeftPanel);
            app.SamplerateEditFieldLabel.Text = 'Sample rate';
            app.SamplerateEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SamplerateEditField.Limits = [1000 1000000];
            app.SamplerateEditField.ValueDisplayFormat = '%.0f';
            app.SamplerateEditField.Value = 10240;
            app.PeriodEditFieldLabel = uilabel(app.LeftPanel);
            app.PeriodEditFieldLabel.Text = 'Period';
            app.PeriodEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PeriodEditField.Limits = [1 Inf];
            app.PeriodEditField.RoundFractionalValues = 'on';
            app.PeriodEditField.Value = 10;
            app.DamageEditFieldLabel = uilabel(app.LeftPanel);
            app.DamageEditFieldLabel.Text = 'Damage (%)';
            app.DamageEditField = uieditfield(app.LeftPanel, 'numeric');
            app.BlocksizeEditFieldLabel = uilabel(app.LeftPanel);
            app.BlocksizeEditFieldLabel.Text = 'Blocksize';
            app.BlocksizeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.BlocksizeEditField.Limits = [100 Inf];
            app.BlocksizeEditField.RoundFractionalValues = 'on';
            app.BlocksizeEditField.ValueDisplayFormat = '%d';
            app.BlocksizeEditField.Value = 4000;
            app.OverlapEditFieldLabel = uilabel(app.LeftPanel);
            app.OverlapEditFieldLabel.Text = 'Overlap';
            app.OverlapEditField = uieditfield(app.LeftPanel, 'numeric');
            app.OverlapEditField.Limits = [0 Inf];
            app.OverlapEditField.Value = 0.75;
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.CenterPanel);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.UIAxes = uiaxes(app.GridLayout3);
            title(app.UIAxes, 'Real Time acceleration')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.UIAxes2_2 = uiaxes(app.GridLayout4);
            xlabel(app.UIAxes2_2, 'Frequency [Hz]')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.RealTimeSignalLabel = uilabel(app.GridLayout2);
            app.RealTimeSignalLabel.Text = 'Real Time Signal';
            app.RightPanel = uipanel(app.GridLayout);
            app.SetInputButton = uibutton(app.RightPanel, 'push');
            app.SetInputButton.ButtonPushedFcn = createCallbackFcn(app, @SetInputButtonPushed, true);
            app.SetInputButton.Enable = 'off';
            app.SetInputButton.Text = 'Set Input';
            app.save = uibutton(app.RightPanel, 'push');
            app.save.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.save.Tag = 'save';
            app.save.Icon = fullfile(pathToMLAPP, 'save.png');
            app.save.Text = '';
            app.ListBoxLabel = uilabel(app.RightPanel);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.RightPanel);
            app.ListBox.Enable = 'off';
            app.ListBox.Value = 'Item 4';
            app.DatabaseEditFieldLabel = uilabel(app.RightPanel);
            app.DatabaseEditFieldLabel.Text = 'Database';
            app.DatabaseEditField = uieditfield(app.RightPanel, 'text');
            app.SetoutputButton = uibutton(app.RightPanel, 'push');
            app.SetoutputButton.ButtonPushedFcn = createCallbackFcn(app, @SetoutputButtonPushed, true);
            app.SetoutputButton.Enable = 'off';
            app.SetoutputButton.Text = 'Set output';
            app.InputsTextAreaLabel = uilabel(app.RightPanel);
            app.InputsTextAreaLabel.Text = 'Inputs';
            app.InputsTextArea = uitextarea(app.RightPanel);
            app.InputsTextArea.Editable = 'off';
            app.OutputsTextAreaLabel = uilabel(app.RightPanel);
            app.OutputsTextAreaLabel.Text = 'Outputs';
            app.OutputsTextArea = uitextarea(app.RightPanel);
            app.OutputsTextArea.Editable = 'off';
            app.Graph2ListBoxLabel = uilabel(app.RightPanel);
            app.Graph2ListBoxLabel.Text = 'Graph 2';
            app.Graph2ListBox = uilistbox(app.RightPanel);
            app.Graph2ListBox.Items = {'PSD', 'FRF', 'Coherence'};
            app.Graph2ListBox.Value = 'PSD';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataAcquisition(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end