classdef MonitoringPlatform < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MonitoringPlatformUIFigure  matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        StructuralHealthMonitoringPlatformLabel  matlab.ui.control.Label
        MonitoringButton            matlab.ui.control.Button
        TrainingValidationButton    matlab.ui.control.Button
        DataAcquisitionButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        DataAcquisitionApp % Data Acquisition app
        ValidationApp %Training Validation app
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DataAcquisitionButton
        function DataAcquisitionButtonPushed(app, event)
            app.DataAcquisitionButton.Enable = "off";
            app.MonitoringPlatformUIFigure.Visible="off";
            app.DataAcquisitionApp = DataAcquisition(app);
        end

        % Close request function: MonitoringPlatformUIFigure
        function MonitoringPlatformUIFigureCloseRequest(app, event)
            delete(app.DataAcquisitionApp)
            delete(app)
            
        end

        % Button pushed function: TrainingValidationButton
        function TrainingValidationButtonPushed(app, event)
            app.TrainingValidationButton.Enable = "off";
            app.MonitoringPlatformUIFigure.Visible="off";
            app.ValidationApp = Validation(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MonitoringPlatformUIFigure = uifigure('Visible', 'off');
            app.MonitoringPlatformUIFigure.Name = 'MonitoringPlatform';
            app.MonitoringPlatformUIFigure.Icon = fullfile(pathToMLAPP, 'Screenshot 2024-03-15 055403.png');
            app.MonitoringPlatformUIFigure.CloseRequestFcn = createCallbackFcn(app, @MonitoringPlatformUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.MonitoringPlatformUIFigure);
            app.DataAcquisitionButton = uibutton(app.GridLayout, 'push');
            app.DataAcquisitionButton.ButtonPushedFcn = createCallbackFcn(app, @DataAcquisitionButtonPushed, true);
            app.DataAcquisitionButton.Text = 'Data Acquisition';
            app.TrainingValidationButton = uibutton(app.GridLayout, 'push');
            app.TrainingValidationButton.ButtonPushedFcn = createCallbackFcn(app, @TrainingValidationButtonPushed, true);
            app.TrainingValidationButton.Text = 'Training/Validation';
            app.MonitoringButton = uibutton(app.GridLayout, 'push');
            app.MonitoringButton.Text = 'Monitoring';
            app.StructuralHealthMonitoringPlatformLabel = uilabel(app.GridLayout);
            app.StructuralHealthMonitoringPlatformLabel.Text = 'Structural Health Monitoring Platform';
            % Show the figure after all components are created
            app.MonitoringPlatformUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MonitoringPlatform

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MonitoringPlatformUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MonitoringPlatformUIFigure)
        end
    end
end