classdef Validation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        ErrorbarsLabel              matlab.ui.control.Label
        DamagedetectionListBox      matlab.ui.control.ListBox
        AnchodeBandaEditField       matlab.ui.control.EditField
        AnchodeBandaEditFieldLabel  matlab.ui.control.Label
        Damage3CheckBox             matlab.ui.control.CheckBox
        Damage2CheckBox             matlab.ui.control.CheckBox
        HealthyCheckBox             matlab.ui.control.CheckBox
        Damage1CheckBox             matlab.ui.control.CheckBox
        GrficaLabel                 matlab.ui.control.Label
        ListBox_4                   matlab.ui.control.ListBox
        ListBox_3                   matlab.ui.control.ListBox
        VSLabel                     matlab.ui.control.Label
        GuardarButton_2             matlab.ui.control.Button
        ListBox_2                   matlab.ui.control.ListBox
        ChanelsListBox              matlab.ui.control.ListBox
        ChanelsListBoxLabel         matlab.ui.control.Label
        ValoresdefrecuenciaLabel    matlab.ui.control.Label
        EditField                   matlab.ui.control.NumericEditField
        ParametersLabel             matlab.ui.control.Label
        AddfileButton               matlab.ui.control.Button
        ActualizarButton            matlab.ui.control.Button
        DatabaseEditField           matlab.ui.control.EditField
        DatabaseEditFieldLabel      matlab.ui.control.Label
        ListBox                     matlab.ui.control.ListBox
        CenterPanel                 matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        UIAxes4                     matlab.ui.control.UIAxes
        UIAxes3                     matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
        RightPanel                  matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        CallingApp % Description
        d % Lista inputs
        Y % Respuesta
        excitation % Input
        N
        Nmc
        fs
        labels
        mu_hat
        Sigma_hat
        fN
        C
        canal
        val1
        val2
        val3
        val4
        val5
        val6
        frec1
        frec2
        Pyy
        BandW
        number
        Q
    end
    
    methods (Access = private)
        
        function dq=Connect(app)
            % Add object connecting to DAQ board
            dq = daq("ni");
            dq.Rate = 10240;% Set sampling rate (just to start, it will be re-adjusted later)
            % Add the channels
            addinput(dq,'cDAQ1Mod1','ai0','IEPE');
        end
        
        function OpeningFcn(app)
            % Check available DAQ systems (may take some time the first time)
            app.d = daqlist; 
            disp(app.d)
            disp('Device information')
            disp(app.d{1,'DeviceInfo'})
            app.ConnectButton.Enable="on";
        end
        
        function valida = Val(app)
            % Funcion para validation
            % We clean the house first!
            clearvars -global -except app.*
            clc

            load(app.DatabaseEditField.Value,"X", "Y","Nmc","fs",'labels')
            app.Y=Y;
            app.excitation=X;
            app.Nmc = Nmc;
            app.N = 10*Nmc;
            app.fs = fs;
            app.labels = labels;
            % Psd comparison
                % Variables
                clr = lines(4);
                M = 400;                                    % Number of samples in each block (blocksize)
                L = floor(app.N/M);                         % Number of (non-overlapping) blocks
                C = unique(app.labels);                                                             % Labels of each class 
                app.C = C;
                                                                                                %   0 : 'Healthy'
                                                                                                %   1 : 'Damage 1'
                                                                                                %   2 : 'Damage 2'
                                                                                                %   3 : 'Damage 3'
                f = app.fs*(0:M/2)/M;                           % Frequency vector
                nC = numel(C);
                class_names = {'Healthy','Damage 1','Damage 2','Damage 3'};  

                % Reorder dimensions so that the third dimension becomes the second
                % Select a signal channel for analysis
                ch = app.canal;                          % Signal channel
                app.Y = squeeze(Y(:,ch,:));                              % System's measured response    
                %app.Y = permute(app.Y, [1, 3, 2]);
                %app.Y = app.Y(:, :, 1);
                app.Pyy = pwelch(app.Y, hann(M), 0, M);
                
                % TRAINING PHASE: Select reference PSDs from each class (we select the first record from each class)
                Pyy0 = app.Pyy(:, 1:100:400);
                % VALIDATION PHASE: Calculate the test statistic from the test signals to each one of the classes
                F = zeros(M/2+1, 4*app.Nmc, nC);
                for i = 1:nC
                    F(:, :, i) = app.Pyy./Pyy0(:, i);          % F-statistic
                end
                dPSD = log(fpdf(F, 2*L, 2*L));      % Log-likelihood evaluated at each frequency
                % Select a reference signal from the healthy state
                ind_ref = 1;                                    % This is the index of our reference signal from the healthy state
                Pyy0 = app.Pyy(:, ind_ref);                          % This is the PSD of the reference signal
                
                % Calculate the test statistic
                F = app.Pyy./Pyy0;                                  % F-statistic for all the PSD samples
                
                % Clear existing plots in UIAxes and UIAxes2
                cla(app.UIAxes);
                cla(app.UIAxes2);
                
                % Plot results in UIAxes
                plot(app.UIAxes, f, 10*log10(Pyy0), 'k', 'LineWidth', 2)
                hold(app.UIAxes, 'on')
                for i = 1:nC
                    plot(app.UIAxes, f, 10*log10(app.Pyy(:, 10+100*(i-1))), 'Color', clr(i,:), 'LineWidth', 1.5)
                end
                grid(app.UIAxes, 'on')
                xlabel(app.UIAxes, 'Frequency [Hz]')
                ylabel(app.UIAxes, 'PSD [dB]')
                legend(app.UIAxes, {'Reference', 'Healthy', 'Damage 1', 'Damage 2', 'Damage 3'}, 'Orientation', 'horizontal', 'Location', 'south')
            % Coherence graph

                % Plot results in UIAxes2
                for i = 1:nC
                    plot(app.UIAxes2, f, dPSD(:, 10+app.Nmc*(i-1)), '.-', 'Color', clr(i,:))
                    hold(app.UIAxes2, 'on')
                end
                grid(app.UIAxes2, 'on')
                xlabel(app.UIAxes2, 'Frequency [Hz]')
                ylabel(app.UIAxes2, 'Log-likelihood')
                legend(app.UIAxes2, {'Healthy', 'Damage 1', 'Damage 2', 'Damage 3'}, 'Orientation', 'horizontal', 'Location', 'northoutside')
                
            % Total log-likelihood
                % Calculate the total log-likelihood for the PSD-based method
                lnL_psd = sum(dPSD);
                % Inicializar una matriz para almacenar los valores que se están ploteando
                values_to_plot = [];
                % Calcular los valores que se están ploteando y almacenarlos en la matriz
                for i = 1:nC
                    values_to_plot = [values_to_plot; lnL_psd(app.labels == app.C(i))];
                end
                % Calcular el mínimo y el máximo de los valores ploteados
                min_y = min(values_to_plot(:));
                max_y = max(values_to_plot(:));
                pi0 = [1 1 1 1]/4;                                  % Prior probability
                
                % Clear existing plots in UIAxes3
                cla(app.UIAxes3);
                
                % Plot the results in UIAxes3
                for i = 1:nC
                    fill(app.UIAxes3, [0 app.Nmc app.Nmc 0]+app.Nmc*(i-1), [min_y min_y -100 -100], 1-0.20*(i-1)*[1 1 1], 'FaceAlpha', 0.25)
                    hold(app.UIAxes3, 'on')
                    plot(app.UIAxes3, (1:app.Nmc)+app.Nmc*(i-1), lnL_psd(app.labels==app.C(i)), 'o', 'Color', clr(i,:), 'MarkerFaceColor', [1 1 1], 'LineWidth', 1.5)
                    text(app.UIAxes3, app.Nmc*(i-1)+2, -110, class_names{i})
                end
                
                grid(app.UIAxes3, 'on')
                xlabel(app.UIAxes3, 'Signal sample')
                ylabel(app.UIAxes3, {'Total log-likelihood'; 'PSD-based method'}, 'Interpreter', 'latex')
                ylim(app.UIAxes3, [min_y -100])

            % ROC curve analysis
                % Initializing computation arrays
                TPR = zeros(4*app.Nmc, nC, 2);
                FPR = zeros(4*app.Nmc, 4, 2);
                ROC_AUC = zeros(2, nC);
                
                [TPR(:, 1, 1), FPR(:, 1, 1), ROC_AUC(1, 1)] = roc_analysis(app.labels~=app.C(1), -lnL_psd);
                
                c_actual = [false(app.Nmc, 1); true(app.Nmc, 1)];
                for j = 1:3
                    [TPR(1:2*app.Nmc, j+1, 1), FPR(1:2*app.Nmc, j+1, 1), ROC_AUC(1, j+1)] = roc_analysis(c_actual, -lnL_psd(app.labels==C(1) | app.labels==C(j+1)));
                end
                
                cases = {'Healthy vs. All', 'Healthy vs. Damage 1', 'Healthy vs. Damage 2', 'Healthy vs. Damage 3'};
                plot_title = 'PSD-based';
            
            % Comparison of PSDs on Healthy case
                % TRAINING PHASE: Select reference PSDs from each class (we select the first record from each class)
                Pyy0 = app.Pyy(:,1:100:400);
                
                % VALIDATION PHASE: Calculate the test statistic from the test signals to each one of the classes
                F = zeros(M/2+1,4*app.Nmc,nC);
                for i=1:nC
                    F(:,:,i) = app.Pyy./Pyy0(:,i);          % F-statistic
                end
                dPSD = log( fpdf( F, 2*L, 2*L ) );      % Log-likelihood evaluated at each frequency
                
                % Clear existing plots in UIAxes4
                cla(app.UIAxes4);
             
                % Obtener el estado de cada checkbox
                chkHealthyState = app.HealthyCheckBox.Value;
                chkDamage1State = app.Damage1CheckBox.Value;
                chkDamage2State = app.Damage2CheckBox.Value;
                chkDamage3State = app.Damage3CheckBox.Value;
                
                % Clear existing plots in UIAxes4
                cla(app.UIAxes4);
                
                % Plotting results based on checkbox states in UIAxes4
                if chkHealthyState
                    plot(app.UIAxes4, f, squeeze(dPSD(:,4,1)), 'o', 'Color', clr(1,:), 'MarkerFaceColor', 'auto', 'MarkerSize', 4);
                    hold(app.UIAxes4, 'on');
                end
                
                if chkDamage1State
                    plot(app.UIAxes4, f, squeeze(dPSD(:,4,2)), 'o', 'Color', clr(2,:), 'MarkerFaceColor', 'auto', 'MarkerSize', 4);
                    hold(app.UIAxes4, 'on');
                end
                
                if chkDamage2State
                    plot(app.UIAxes4, f, squeeze(dPSD(:,4,3)), 'o', 'Color', clr(3,:), 'MarkerFaceColor', 'auto', 'MarkerSize', 4);
                    hold(app.UIAxes4, 'on');
                end
                
                if chkDamage3State
                    plot(app.UIAxes4, f, squeeze(dPSD(:,4,4)), 'o', 'Color', clr(4,:), 'MarkerFaceColor', 'auto', 'MarkerSize', 4);
                    hold(app.UIAxes4, 'on');
                end
                
                grid(app.UIAxes4, 'on');
                ylabel(app.UIAxes4, 'Log-likelihood');
                title(app.UIAxes4, 'Comparison on Healthy State');
                xlabel(app.UIAxes4, 'Frequency [Hz]');
                
                
                % Ajustar la ventana para que muestre desde el mínimo al máximo en x e y
                axis(app.UIAxes4, 'tight');


        end

       
        function classification = Clas(app)
            % Funcion para classification

            %app.C = unique(app.labels);                                                             % Labels of each class 
                                                                                %   0 : 'Healthy'
                                                                                %   1 : 'Damage 1'
                                                                                %   2 : 'Damage 2'
                                                                                %   3 : 'Damage 3'

            nC = numel(app.C);                                                                  % Number of classes
            
            class_names = {'Healthy','Damage 1','Damage 2','Damage 3'};                     % Class names
            
            
            % Calculate Welch's PSD estimate
            M = 400;
            L = floor( app.N/M );
            %app.Pyy = pwelch( app.Y, hann(M), 0, M );
            f = app.fs*(0:M/2)/M;                         % Frequency vector

            % Matriz de confusión
                % Calculate Welch's PSD estimate
                M = 400;
                % TRAINING PHASE: Select reference PSDs from each class (we select the first record from each class)
                Pyy0 = app.Pyy(:,1:100:400);
                nC = numel(app.C);                                                                  % Number of classes
                % VALIDATION PHASE: Calculate the test statistic from the test signals to each one of the classes
                F = zeros(M/2+1,4*app.Nmc,nC);
                for i=1:nC
                    F(:,:,i) = app.Pyy./Pyy0(:,i);          % F-statistic
                end
                L = floor(10);
                dPSD = log( fpdf( F, 2*L, 2*L ) );      % Log-likelihood evaluated at each frequency                
                CM = zeros(nC);
                % Assign prior probabilities
                pi0 = [1e8 1 1 1e8];
                pi0 = pi0 / sum(pi0);
                % Calculate a single damage classification statistic per class
                lnL_psd = squeeze( sum( dPSD ) );           % Total likelihood
                [~,c_hat] = max(lnL_psd+log(pi0),[],2);
                c_hat = app.C(c_hat);
        
                for i=1:nC
                    for j=1:nC
                        CM(i,j) = sum( c_hat( app.labels == app.C(i) ) == app.C(j) ) / sum( app.labels == app.C(i) );
                    end
                end
                % Clear existing plots in UIAxes
                cla(app.UIAxes);
                class_names = {'Healthy','Damage 1','Damage 2','Damage 3'};                     % Class names
                
                % Plot the obtained confusion matrix in UIAxes
                imagesc(app.UIAxes, CM'), axis(app.UIAxes, 'square')
                set(app.UIAxes,'XTick',1:nC,'YTick',1:nC,'XTickLabel',class_names,'YTickLabel',class_names)
                set(app.UIAxes,'CLim',[0 1])
                for i=1:nC
                    for j=1:nC
                        text('Parent', app.UIAxes, 'Position',[i,j], 'String', num2str(CM(i,j),'%0.3f'), 'HorizontalAlignment', 'center', 'Color', [1 1 1])
                    end
                end
                cbar = colorbar(app.UIAxes);
                cbar.Label.String = 'Rate';
                title(app.UIAxes, 'Confusion matrix')
                xlabel(app.UIAxes, 'Actual class')
                ylabel(app.UIAxes, 'Predicted class')
                
            %% PSD medios
            % Clear existing plots in UIAxes
            cla(app.UIAxes2);
            % Plot results
            pt = zeros(nC,1);
            
            for i=1:nC
                xx = [f fliplr(f)]';
                yy = 10*log10([prctile( app.Pyy(:,app.labels==app.C(i)), 5, 2 );
                    flipud( prctile( app.Pyy(:,app.labels==app.C(i)), 95, 2 )) ]);
                
                clr = lines(4);
            
                fill(app.UIAxes2, xx, yy, clr(i,:), 'LineStyle', 'none', 'FaceAlpha', 0.25);
                hold(app.UIAxes2, 'on');
                pt(i) = plot(app.UIAxes2, f, 10*log10(mean(app.Pyy(:,app.labels==app.C(i)),2)), 'Color', clr(i,:));
                xlabel(app.UIAxes2, 'Frequency [Hz]');
                ylabel(app.UIAxes2, 'PSD');
                grid(app.UIAxes2, 'on');
            end
            legend(app.UIAxes2, pt, class_names, 'Orientation', 'horizontal', 'Location', 'south');
            %% Bandas de frecuencia
            % PSD estimation settings
            nf = 1024;                                                                              % Blocksize (samples)
            nover = nf*3/4;                                                                         % Number of overlapping samples
            win = hann(nf);                                                                         % Spectral window
            
            Ndof = 6; 
            Nc = numel(unique(app.labels));                                                            % Number of classes (structural conditions)
            
            % Calculate PSDs
            [app.Pyy,frec] = pwelch(app.Y, win, nover, nf, app.fs);
            
            % Define the spectral sub-bands
            fc = [app.val1; app.val2; app.val3; app.val4; app.val5; app.val6];                                                % Define central frequencies of the spectral sub-bands
            fband = round([fc-app.BandW fc+app.BandW]*nf/app.fs);                                                     % Define bandwidth of each spectral sub-band
            
            % Plotting the spectral sub-bands
            cla(app.UIAxes3);  % Clear existing plots in UIAxes3
            Po = mean(app.Pyy(:,app.labels==0), 2);
            clr = lines(Ndof);
            for i=1:Ndof
                ind = fband(i,1):fband(i,2);
                fill(app.UIAxes3, [frec(ind); flipud(frec(ind))], [Po(ind); zeros(numel(ind),1)], clr(2,:))
                hold(app.UIAxes3, 'on');
            end
            plot(app.UIAxes3, frec, Po, '-k', 'LineWidth', 2)
            xlim(app.UIAxes3, [fc(1)-70, fc(end)+70])
            % Set y-axis limits
            ylim(app.UIAxes3, [min(Po), max(Po)+(0.1*max(Po))]);
            grid(app.UIAxes3, 'on')
            xlabel(app.UIAxes3, 'Frequency [Hz]')
            %ylabel(app.UIAxes3, 'PSD [(m/s^2)/Hz]')

            %% Charts
            % Calculate the spectral features
            Pbar = zeros(4*app.Nmc,Ndof);
            nu = zeros(4*app.Nmc,Ndof);
            BW = zeros(4*app.Nmc,Ndof);
            for i=1:Ndof
                ind = fband(i,1):fband(i,2);
                DeltaF = diff(fband(i,:))*app.fs/nf;
                Pbar(:,i) = trapz(app.Pyy(ind,:),1) / DeltaF;
                nu(:,i) = (trapz(frec(ind).*app.Pyy(ind,:), 1)'/(diff(fband(i,:))*app.fs/nf)) ./ Pbar(:,i);
                e = frec(ind)-nu(:,i)';
                BW(:,i) = sqrt((trapz((e.^2) .* app.Pyy(ind,:), 1)'/(diff(fband(i,:))*app.fs/nf)) ./ Pbar(:,i));
            end
            
            % Scatterplot of the obtained DSFs at different conditions
            Q = [log10(Pbar) nu BW];
            Q = Q - mean(Q(app.labels==0,:));
            Q = Q ./ std(Q);
            app.Q = Q;
            cla(app.UIAxes4);  % Clear existing plots in UIAxes4
            
            % Modificar el arreglo de estados
            for i = 1:4
                inicio = (i - 1) * 100 + 1;
                fin = inicio + 99;
                app.labels(inicio:fin) = i - 1;
            end
            
            for j=app.number:app.number
                hold(app.UIAxes4, 'on');
                for k=1:4
                ind = (1:6)+6*(j-1);
                mnQ = median(Q(app.labels==k-1,ind));
                dnQ = mnQ - prctile(Q(app.labels==k-1,ind),5);
                upQ = prctile(Q(app.labels==k-1,ind),95) - mnQ;
                errorbar(app.UIAxes4, (1:6)+0.1*(k-1), mnQ, dnQ, upQ, 'o', 'LineStyle', 'none')
                end
            end
            
            % Configuración del gráfico
            xlim(app.UIAxes4, [0.5, 7]);
            xlabel(app.UIAxes4, 'Feature index');
            ylabel(app.UIAxes4, 'Normalized feature value');
            legend(app.UIAxes4, {'Healthy','Damage 1','Damage 2','Damage 3'}, 'Location', 'northoutside', 'Orientation', 'horizontal');
            grid(app.UIAxes4, 'on');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.CallingApp = caller;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.ValidationButton.Enable = "on";
            app.CallingApp.MonitoringPlatformUIFigure.Visible="on";
            delete(app)
        end

        % Callback function
        function ConnectButtonPushed(app, event)

        end

        % Value changed function: DatabaseEditField
        function DatabaseEditFieldValueChanged(app, event)
            value = app.DatabaseEditField.Value;
        end

        % Button pushed function: AddfileButton
        function AddfileButtonPushed(app, event)
            app.canal = 1;
            [file] = uigetfile('*.mat');
            app.DatabaseEditField.Value=file;
            figure(app.UIFigure);
            load(app.DatabaseEditField.Value,"X", "Y","Nmc","fs",'labels')
            app.Y=Y;
            app.excitation=X;
            app.Nmc = Nmc;
            app.N = 10*Nmc;
            app.fs = fs;
            app.labels = labels;
            %{
            Clasificación mediante frecuencias naturales
            % Loading data
                load("DSFs_Modal.mat","fN")
                app.fN = fN
                frec1 = 3;
                frec2 = 6;
            %}
            if app.ListBox.Value == "Validation"
                Val(app)
            end
            % Asignar las frecuencias
            app.val1 = 295;
            app.val2 = 570;
            app.val3 = 940;
            app.val4 = 1385;
            app.val5 = 1950;
            app.val6 = 2580;
            app.BandW = 50;
            app.EditField.Value = app.val1;
            app.number = 1;
        end

        % Callback function: ActualizarButton, ListBox
        function ActualizarButtonPushed(app, event)
        if app.ListBox.Value == "Validation"
            Val(app)
        else
            Clas(app)
        end
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            % Dependiendo de la opcion que se elija se van a mostrar
            % diferentes graficas.
            if value == "Validation"
               Val(app)
            else
               Clas(app)
            end
        end

        % Value changed function: ChanelsListBox
        function ChanelsListBoxValueChanged(app, event)
            value = app.ChanelsListBox.Value;
            if value == "Sensor 1"
               app.canal = 1;
            elseif value == "Sensor 2" 
               app.canal = 2;
            else
               app.canal = 3;
            end
        end

        % Button pushed function: GuardarButton_2
        function GuardarButton_2Pushed(app, event)
            %Función a implementar a futuro 
            % (Se necesita entender como obtener los datos de psd en dicha frecuencia)
            if app.ListBox_2.Value == "Frecuencia 1"
                app.val1 = app.EditField.Value;
            elseif app.ListBox_2.Value == "Frecuencia 2"
                app.val2 = app.EditField.Value;
            elseif app.ListBox_2.Value == "Frecuencia 3"
                app.val3 = app.EditField.Value;
            elseif app.ListBox_2.Value == "Frecuencia 4"
                app.val4 = app.EditField.Value;
            elseif app.ListBox_2.Value == "Frecuencia 5"
                app.val5 = app.EditField.Value;
            elseif app.ListBox_2.Value == "Frecuencia 6"
                app.val6 = app.EditField.Value;
            end
        end

        % Callback function
        function GuardarButtonPushed(app, event)
            %Gráficas de classification
            
            
        end

        % Value changed function: ListBox_3
        function ListBox_3ValueChanged(app, event)
            value = app.ListBox_3.Value;
            %Condicionales para la primera frecuencia
            if app.ListBox_3.Value == "Frecuencia 1"
                app.frec1 = 1;
            elseif app.ListBox_3.Value == "Frecuencia 2"
                app.frec1 = 2;
            elseif app.ListBox_3.Value == "Frecuencia 3"
                app.frec1 = 3;
            elseif app.ListBox_3.Value == "Frecuencia 4"
                app.frec1 = 4;
            elseif app.ListBox_3.Value == "Frecuencia 5"
                app.frec1 = 5;
            elseif app.ListBox_3.Value == "Frecuencia 6"
                app.frec1 = 6;
            end
        end

        % Value changed function: ListBox_4
        function ListBox_4ValueChanged(app, event)
            value = app.ListBox_4.Value;
            %Condicionales para la segunda frecuencia
            if app.ListBox_4.Value == "Frecuencia 1"
                app.frec2 = 1;
            elseif app.ListBox_4.Value == "Frecuencia 2"
                app.frec2 = 2;
            elseif app.ListBox_4.Value == "Frecuencia 3"
                app.frec2 = 3;
            elseif app.ListBox_4.Value == "Frecuencia 4"
                app.frec2 = 4;
            elseif app.ListBox_4.Value == "Frecuencia 5"
                app.frec2 = 5;
            elseif app.ListBox_4.Value == "Frecuencia 6"
                app.frec2 = 6;
            end
        end

        % Value changed function: HealthyCheckBox
        function HealthyCheckBoxValueChanged(app, event)
            value = app.HealthyCheckBox.Value;
            if app.ListBox.Value == "Validation"
                Val(app)
            end
        end

        % Value changed function: Damage1CheckBox
        function Damage1CheckBoxValueChanged(app, event)
            value = app.Damage1CheckBox.Value;
            if app.ListBox.Value == "Validation"
                Val(app)
            end
        end

        % Value changed function: Damage2CheckBox
        function Damage2CheckBoxValueChanged(app, event)
            value = app.Damage2CheckBox.Value;
            if app.ListBox.Value == "Validation"
                Val(app)
            end
        end

        % Value changed function: Damage3CheckBox
        function Damage3CheckBoxValueChanged(app, event)
            value = app.Damage3CheckBox.Value;
            if app.ListBox.Value == "Validation"
                Val(app)
            end
        end

        % Value changed function: AnchodeBandaEditField
        function AnchodeBandaEditFieldValueChanged(app, event)
            value = app.AnchodeBandaEditField.Value;
            app.BandW = value;
        end

        % Value changed function: ListBox_2
        function ListBox_2ValueChanged(app, event)
            value = app.ListBox_2.Value;
            if app.ListBox_2.Value == "Frecuencia 1"
                app.EditField.Value = app.val1;
            elseif app.ListBox_2.Value == "Frecuencia 2"
                app.EditField.Value = app.val2;
            elseif app.ListBox_2.Value == "Frecuencia 3"
                app.EditField.Value = app.val3;
            elseif app.ListBox_2.Value == "Frecuencia 4"
                app.EditField.Value = app.val4;
            elseif app.ListBox_2.Value == "Frecuencia 5"
                app.EditField.Value = app.val5;
            elseif app.ListBox_2.Value == "Frecuencia 6"
                app.EditField.Value = app.val6;
            end
        end

        % Value changed function: DamagedetectionListBox
        function DamagedetectionListBoxValueChanged(app, event)
            value = app.DamagedetectionListBox.Value;
            if value == "Graph 1"
                app.number = 1;
            elseif value == "Graph 2"
                app.number = 2;
            elseif value == "Graph 3"
                app.number= 3;
            end

            cla(app.UIAxes4);  % Clear existing plots in UIAxes4
            
            % Modificar el arreglo de estados
            for i = 1:4
                inicio = (i - 1) * 100 + 1;
                fin = inicio + 99;
                app.labels(inicio:fin) = i - 1;
            end
            
            for j=app.number:app.number
                hold(app.UIAxes4, 'on');
                for k=1:4
                ind = (1:6)+6*(j-1);
                mnQ = median(app.Q(app.labels==k-1,ind));
                dnQ = mnQ - prctile(app.Q(app.labels==k-1,ind),5);
                upQ = prctile(app.Q(app.labels==k-1,ind),95) - mnQ;
                errorbar(app.UIAxes4, (1:6)+0.1*(k-1), mnQ, dnQ, upQ, 'o', 'LineStyle', 'none')
                end
            end
            
            % Configuración del gráfico
            xlim(app.UIAxes4, [0.5, 7]);
            xlabel(app.UIAxes4, 'Feature index');
            ylabel(app.UIAxes4, 'Normalized feature value');
            legend(app.UIAxes4, {'Healthy','Damage 1','Damage 2','Damage 3'}, 'Location', 'northoutside', 'Orientation', 'horizontal');
            grid(app.UIAxes4, 'on');

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {726, 726, 726};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {726, 726};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {390, '1x', 12};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ListBox = uilistbox(app.LeftPanel);
            app.ListBox.Items = {'Validation', 'Classification'};
            app.ListBox.Multiselect = 'on';
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.DoubleClickedFcn = createCallbackFcn(app, @ActualizarButtonPushed, true);
            app.ListBox.Value = {'Validation'};
            app.DatabaseEditFieldLabel = uilabel(app.LeftPanel);
            app.DatabaseEditFieldLabel.Text = 'Database';
            app.DatabaseEditField = uieditfield(app.LeftPanel, 'text');
            app.DatabaseEditField.ValueChangedFcn = createCallbackFcn(app, @DatabaseEditFieldValueChanged, true);
            app.ActualizarButton = uibutton(app.LeftPanel, 'push');
            app.ActualizarButton.ButtonPushedFcn = createCallbackFcn(app, @ActualizarButtonPushed, true);
            app.ActualizarButton.Text = 'Actualizar';
            app.AddfileButton = uibutton(app.LeftPanel, 'push');
            app.AddfileButton.ButtonPushedFcn = createCallbackFcn(app, @AddfileButtonPushed, true);
            app.AddfileButton.Text = 'Add file';
            app.ParametersLabel = uilabel(app.LeftPanel);
            app.ParametersLabel.Text = 'Parameters';
            app.EditField = uieditfield(app.LeftPanel, 'numeric');
            app.ValoresdefrecuenciaLabel = uilabel(app.LeftPanel);
            app.ValoresdefrecuenciaLabel.Text = 'Valores de frecuencia';
            app.ChanelsListBoxLabel = uilabel(app.LeftPanel);
            app.ChanelsListBoxLabel.Text = 'Chanels';
            app.ChanelsListBox = uilistbox(app.LeftPanel);
            app.ChanelsListBox.Items = {'Sensor 1', 'Sensor 2', 'Sensor 3'};
            app.ChanelsListBox.ValueChangedFcn = createCallbackFcn(app, @ChanelsListBoxValueChanged, true);
            app.ChanelsListBox.Value = 'Sensor 1';
            app.ListBox_2 = uilistbox(app.LeftPanel);
            app.ListBox_2.Items = {'Frecuencia 1', 'Frecuencia 2', 'Frecuencia 3', 'Frecuencia 4', 'Frecuencia 5', 'Frecuencia 6'};
            app.ListBox_2.ValueChangedFcn = createCallbackFcn(app, @ListBox_2ValueChanged, true);
            app.ListBox_2.Value = 'Frecuencia 1';
            app.GuardarButton_2 = uibutton(app.LeftPanel, 'push');
            app.GuardarButton_2.ButtonPushedFcn = createCallbackFcn(app, @GuardarButton_2Pushed, true);
            app.GuardarButton_2.Text = 'Guardar';
            app.VSLabel = uilabel(app.LeftPanel);
            app.VSLabel.Text = 'VS';
            app.ListBox_3 = uilistbox(app.LeftPanel);
            app.ListBox_3.Items = {'Frecuencia 1', 'Frecuencia 2', 'Frecuencia 3', 'Frecuencia 4', 'Frecuencia 5', 'Frecuencia 6'};
            app.ListBox_3.ValueChangedFcn = createCallbackFcn(app, @ListBox_3ValueChanged, true);
            app.ListBox_3.Value = 'Frecuencia 1';
            app.ListBox_4 = uilistbox(app.LeftPanel);
            app.ListBox_4.Items = {'Frecuencia 1', 'Frecuencia 2', 'Frecuencia 3', 'Frecuencia 4', 'Frecuencia 5', 'Frecuencia 6'};
            app.ListBox_4.ValueChangedFcn = createCallbackFcn(app, @ListBox_4ValueChanged, true);
            app.ListBox_4.Value = 'Frecuencia 1';
            app.GrficaLabel = uilabel(app.LeftPanel);
            app.GrficaLabel.Text = 'Gráfica';
            app.Damage1CheckBox = uicheckbox(app.LeftPanel);
            app.Damage1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Damage1CheckBoxValueChanged, true);
            app.Damage1CheckBox.Text = 'Damage 1';
            app.HealthyCheckBox = uicheckbox(app.LeftPanel);
            app.HealthyCheckBox.ValueChangedFcn = createCallbackFcn(app, @HealthyCheckBoxValueChanged, true);
            app.HealthyCheckBox.Text = 'Healthy';
            app.HealthyCheckBox.Value = true;
            app.Damage2CheckBox = uicheckbox(app.LeftPanel);
            app.Damage2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Damage2CheckBoxValueChanged, true);
            app.Damage2CheckBox.Text = 'Damage 2';
            app.Damage3CheckBox = uicheckbox(app.LeftPanel);
            app.Damage3CheckBox.ValueChangedFcn = createCallbackFcn(app, @Damage3CheckBoxValueChanged, true);
            app.Damage3CheckBox.Text = 'Damage 3';
            app.AnchodeBandaEditFieldLabel = uilabel(app.LeftPanel);
            app.AnchodeBandaEditFieldLabel.Text = 'Ancho de Banda';
            app.AnchodeBandaEditField = uieditfield(app.LeftPanel, 'text');
            app.AnchodeBandaEditField.ValueChangedFcn = createCallbackFcn(app, @AnchodeBandaEditFieldValueChanged, true);
            app.AnchodeBandaEditField.Value = '50';
            app.DamagedetectionListBox = uilistbox(app.LeftPanel);
            app.DamagedetectionListBox.Items = {'Graph 1', 'Graph 2', 'Graph 3'};
            app.DamagedetectionListBox.ValueChangedFcn = createCallbackFcn(app, @DamagedetectionListBoxValueChanged, true);
            app.DamagedetectionListBox.Value = 'Graph 1';
            app.ErrorbarsLabel = uilabel(app.LeftPanel);
            app.ErrorbarsLabel.Text = 'Error bars';
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.CenterPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout2);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout2);
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.GridLayout2);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Validation(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end